classdef gith < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        LeftPanel                   matlab.ui.container.Panel
        Image                       matlab.ui.control.Image
        ImportSequenceButton        matlab.ui.control.Button
        SequenceEditField           matlab.ui.control.EditField
        SequenceEditFieldLabel      matlab.ui.control.Label
        InstantPostButton           matlab.ui.control.StateButton
        debugButton                 matlab.ui.control.Button
        RPIIPAddressEditField       matlab.ui.control.EditField
        RPIIPAddressEditFieldLabel  matlab.ui.control.Label
        CenterPanel                 matlab.ui.container.Panel
        SequenceInspectPanel        matlab.ui.container.Panel
        Tree                        matlab.ui.container.Tree
        RightPanel                  matlab.ui.container.Panel
        GridLayout2                 matlab.ui.container.GridLayout
        GridLayout5                 matlab.ui.container.GridLayout
        HOLDButton                  matlab.ui.control.Button
        SEQUENCESTARTButton         matlab.ui.control.Button
        MANUALVALVEPOSTButton       matlab.ui.control.Button
        GridLayout3                 matlab.ui.container.GridLayout
        GridLayout4                 matlab.ui.container.GridLayout
        IGNITEButton                matlab.ui.control.Button
        LOXPurgeButton              matlab.ui.control.StateButton
        FUELPurgeButton             matlab.ui.control.StateButton
        MAINButton                  matlab.ui.control.StateButton
        LOXVentButton               matlab.ui.control.StateButton
        FUELVentButton              matlab.ui.control.StateButton
        LOXPressButton              matlab.ui.control.StateButton
        FUELPressButton             matlab.ui.control.StateButton
        ManualValveControlLabel     matlab.ui.control.Label
        ABORTButton                 matlab.ui.control.Button
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = private)
        instantPost; % Toggles instant button posting to http
        desiredState; % weird fix?? POP
        sequenceString; % cringe
        refreshTimer;   % App refresh timer
    end
    
    methods (Access = public)
        function refreshTick(app)
            % Do nothing
            disp(app.FUELPressButton.Value)
            return 
        end  
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.desiredState = StateMachine();
            value = app.InstantPostButton.Value;
            app.instantPost = value;
            app.desiredState.ip = app.RPIIPAddressEditField.Value;
            
            % Destroy all timers from previous instances of the app
            % Locate all timers
            TimerList = timerfindall;
            % If there are timers
            if ~isempty(TimerList)
                % Check them all
                for n = 1:length(TimerList)
                    % If the timer name matches the static name
                    if convertCharsToStrings(TimerList(n).Name) == 'MATLAPP_RefreshTimer'
                        % Stop the timer
                        stop(TimerList(n));
                        % Then delete the timer
                        delete(TimerList(n));
                    end
                end
            end
            % Build a new timer
            app.refreshTimer = timer( ...
                'Name', 'MATLAPP_RefreshTimer', ...
                'Period', 0.1, ...
                'ExecutionMode', 'fixedSpacing', ...
                'TasksToExecute', Inf, ...
                'TimerFcn', @(~,~)app.refreshTick());
            % And start it
            start(app.refreshTimer);
        end

        % Value changed function: RPIIPAddressEditField
        function RPIIPAddressEditFieldValueChanged(app, event)
            value = app.RPIIPAddressEditField.Value;
            app.desiredState.ip = value;
        end

        % Value changed function: FUELPressButton
        function FUELPressButtonValueChanged(app, event)
            value = app.FUELPressButton.Value;
            app.desiredState.FUEL_Press = value;
            
            if(app.instantPost)
                response = app.desiredState.post();
                app.FUELPressButton.Text=["FUEL-Press:", response.FUEL_Press];
            end
        end

        % Value changed function: LOXPressButton
        function LOXPressButtonValueChanged(app, event)
            value = app.LOXPressButton.Value;
            app.desiredState.LOX_Press = value;
            
            if(app.instantPost)
                response = app.desiredState.post();
                app.LOXPressButton.Text=["LOX-Press:", response.LOX_Press];
            end
        end

        % Callback function
        function InstantValvePostSwitchValueChanged(app, event)
            value = app.InstantValvePostSwitch.Value;
            app.instantPost = value;
        end

        % Button pushed function: MANUALVALVEPOSTButton
        function MANUALVALVEPOSTButtonPushed(app, event)
            response = app.desiredState.post();
            app.FUELPressButton.Text=["FUEL-Press:", response.FUEL_Press];
            app.LOXPressButton.Text=["LOX-Press:", response.LOX_Press];
            app.FUELVentButton.Text=["FUEL-Vent:", response.FUEL_Vent];
            app.LOXVentButton.Text=["LOX-Vent:", response.LOX_Vent];
            app.MAINButton.Text=["MAIN:", response.MAIN];
            app.FUELPurgeButton.Text=["FUEL-Purge:", response.FUEL_Purge];
            app.LOXPurgeButton.Text=["LOX-Purge", response.LOX_Purge];
        end

        % Value changed function: FUELVentButton
        function FUELVentButtonValueChanged(app, event)
            value = app.FUELVentButton.Value;
            app.desiredState.FUEL_Vent = value;
            
            if(app.instantPost)
                response = app.desiredState.post();
                app.FUELVentButton.Text=["FUEL-Vent:", response.FUEL_Vent];
            end
        end

        % Value changed function: LOXVentButton
        function LOXVentButtonValueChanged(app, event)
            value = app.LOXVentButton.Value;
            app.desiredState.LOX_Vent = value;
            
            if(app.instantPost)
                response = app.desiredState.post();
                app.LOXVentButton.Text=["LOX-Vent:", response.LOX_Vent];
            end
        end

        % Value changed function: MAINButton
        function MAINButtonValueChanged(app, event)
            value = app.MAINButton.Value;
            app.desiredState.MAIN = value;
            
            if(app.instantPost)
                response = app.desiredState.post();
                app.MAINButton.Text=["MAIN:", response.MAIN];
            end
        end

        % Value changed function: FUELPurgeButton
        function FUELPurgeButtonValueChanged(app, event)
            value = app.FUELPurgeButton.Value;
            app.desiredState.FUEL_Purge = value;
            
            if(app.instantPost)
                response = app.desiredState.post();
                app.FUELPurgeButton.Text=["FUEL-Purge:", response.FUEL_Purge];
            end
        end

        % Value changed function: LOXPurgeButton
        function LOXPurgeButtonValueChanged(app, event)
            value = app.LOXPurgeButton.Value;
            app.desiredState.LOX_Purge = value;
            
            if(app.instantPost)
                response = app.desiredState.post();
                app.LOXPurgeButton.Text=["LOX-Purge", response.LOX_Purge];
            end
        end

        % Button pushed function: debugButton
        function debugButtonPushed(app, event)
            print(app.desiredState)
        end

        % Callback function
        function InstaPostToggleSwitchValueChanged(app, event)
            value = app.InstaPostToggleSwitch.Value;
            app.instantPost = value;
        end

        % Value changed function: InstantPostButton
        function InstantPostButtonValueChanged(app, event)
            value = app.InstantPostButton.Value;
            app.instantPost = value;
        end

        % Button pushed function: ImportSequenceButton
        function ImportSequenceButtonPushed(app, event)
            % wipe tree nodes to write updated sequence (for multiple
            % imports)
            app.Tree.Children.delete();
            app.desiredState.parseSequence(app.SequenceEditField.Value);
            % disp(app.desiredState.sequence);

            % read json state "name" indices to reference individual states
            names = fieldnames(app.desiredState.sequence);
            seq = app.desiredState.sequence;
            firstLevel = {}; % weird fix
            
            % construct node tree to display sequence on GUI
            for i = 1:length(names)
                %disp(names{i})
                firstLevel{i} = uitreenode( app.Tree,'Text', getfield(seq, names{i}).Name );
%                 disp(firstLevel)
%                 disp(firstLevel{i})
                a = uitreenode( firstLevel{i},'Text', sprintf('Duration: %s s', num2str(getfield(seq, names{i}).Duration) ) );
                b = uitreenode( firstLevel{i},'Text','State');
                
                KeyList = {
                    'FUEL_Press',
                    'LOX_Press',
                    'FUEL_Vent',
                    'LOX_Vent',
                    'MAIN',
                    'FUEL_Purge',
                    'LOX_Purge'
                };
                
                % show valve states in tree
                state = getfield(seq, names{i}).State;
                for j = 1:length(KeyList)
                    c = uitreenode (b,'Text', sprintf('%s: %s', KeyList{j}, num2str(getfield(state,KeyList{j})) ));
                end
                
            end
%             a = uitreenode( firstLevel(i),'Text', getfield(seq, names{i}).Duration );
            
            expand(app.Tree)
%             disp(app.desiredState.timers(1).startDelay)
        end

        % Value changed function: SequenceEditField
        function SequenceEditFieldValueChanged(app, event)
            %value = str(app.SequenceEditField.Value); % wEiRd FiX
            %app.sequenceString = value;
        end

        % Button pushed function: SEQUENCESTARTButton
        function SEQUENCESTARTButtonPushed(app, event)
%             tic
            start(app.desiredState.timers(1))
        end

        % Button pushed function: HOLDButton
        function HOLDButtonPushed(app, event)
            app.desiredState.stopTimer();
        end

        % Button pushed function: ABORTButton
        function ABORTButtonPushed(app, event)
            app.desiredState.stopTimer();
            
            % reset timer array with parseSequence method
            app.desiredState.parseSequence(app.SequenceEditField.Value);
            
            % hardcode safe state
            app.desiredState.safe();
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {837, 837, 837};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {837, 837};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {282, '1x', 267};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.RPIIPAddressEditFieldLabel = uilabel(app.LeftPanel);
            app.RPIIPAddressEditFieldLabel.Text = 'RPI IP Address';
            app.RPIIPAddressEditField = uieditfield(app.LeftPanel, 'text');
            app.RPIIPAddressEditField.ValueChangedFcn = createCallbackFcn(app, @RPIIPAddressEditFieldValueChanged, true);
            app.RPIIPAddressEditField.Value = '192.168.137.10';
            app.debugButton = uibutton(app.LeftPanel, 'push');
            app.debugButton.ButtonPushedFcn = createCallbackFcn(app, @debugButtonPushed, true);
            app.debugButton.Enable = 'off';
            app.debugButton.Visible = 'off';
            app.debugButton.Text = 'debug';
            app.InstantPostButton = uibutton(app.LeftPanel, 'state');
            app.InstantPostButton.ValueChangedFcn = createCallbackFcn(app, @InstantPostButtonValueChanged, true);
            app.InstantPostButton.Text = 'Instant Post';
            app.SequenceEditFieldLabel = uilabel(app.LeftPanel);
            app.SequenceEditFieldLabel.Text = 'Sequence';
            app.SequenceEditField = uieditfield(app.LeftPanel, 'text');
            app.SequenceEditField.ValueChangedFcn = createCallbackFcn(app, @SequenceEditFieldValueChanged, true);
            app.SequenceEditField.Value = 'sequence.json';
            app.ImportSequenceButton = uibutton(app.LeftPanel, 'push');
            app.ImportSequenceButton.ButtonPushedFcn = createCallbackFcn(app, @ImportSequenceButtonPushed, true);
            app.ImportSequenceButton.Text = 'Import Sequence';
            app.Image = uiimage(app.LeftPanel);
            app.Image.ImageSource = 'logo_transparent.svg';
            app.CenterPanel = uipanel(app.GridLayout);
            app.SequenceInspectPanel = uipanel(app.CenterPanel);
            app.SequenceInspectPanel.Title = 'Sequence Inspect';
            app.Tree = uitree(app.SequenceInspectPanel);
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.RightPanel);
            app.ABORTButton = uibutton(app.GridLayout2, 'push');
            app.ABORTButton.ButtonPushedFcn = createCallbackFcn(app, @ABORTButtonPushed, true);
            app.ABORTButton.Text = 'ABORT';
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.ManualValveControlLabel = uilabel(app.GridLayout3);
            app.ManualValveControlLabel.Text = 'Manual Valve Control';
            app.GridLayout4 = uigridlayout(app.GridLayout3);
            app.FUELPressButton = uibutton(app.GridLayout4, 'state');
            app.FUELPressButton.ValueChangedFcn = createCallbackFcn(app, @FUELPressButtonValueChanged, true);
            app.FUELPressButton.Text = 'FUEL-Press';
            app.LOXPressButton = uibutton(app.GridLayout4, 'state');
            app.LOXPressButton.ValueChangedFcn = createCallbackFcn(app, @LOXPressButtonValueChanged, true);
            app.LOXPressButton.Text = 'LOX-Press';
            app.FUELVentButton = uibutton(app.GridLayout4, 'state');
            app.FUELVentButton.ValueChangedFcn = createCallbackFcn(app, @FUELVentButtonValueChanged, true);
            app.FUELVentButton.Text = 'FUEL-Vent';
            app.FUELVentButton.Value = true;
            app.LOXVentButton = uibutton(app.GridLayout4, 'state');
            app.LOXVentButton.ValueChangedFcn = createCallbackFcn(app, @LOXVentButtonValueChanged, true);
            app.LOXVentButton.Text = 'LOX-Vent';
            app.LOXVentButton.Value = true;
            app.MAINButton = uibutton(app.GridLayout4, 'state');
            app.MAINButton.ValueChangedFcn = createCallbackFcn(app, @MAINButtonValueChanged, true);
            app.MAINButton.Text = 'MAIN';
            app.FUELPurgeButton = uibutton(app.GridLayout4, 'state');
            app.FUELPurgeButton.ValueChangedFcn = createCallbackFcn(app, @FUELPurgeButtonValueChanged, true);
            app.FUELPurgeButton.Text = 'FUEL-Purge';
            app.LOXPurgeButton = uibutton(app.GridLayout4, 'state');
            app.LOXPurgeButton.ValueChangedFcn = createCallbackFcn(app, @LOXPurgeButtonValueChanged, true);
            app.LOXPurgeButton.Text = 'LOX-Purge';
            app.IGNITEButton = uibutton(app.GridLayout4, 'push');
            app.IGNITEButton.Text = 'IGNITE';
            app.GridLayout5 = uigridlayout(app.GridLayout2);
            app.MANUALVALVEPOSTButton = uibutton(app.GridLayout5, 'push');
            app.MANUALVALVEPOSTButton.ButtonPushedFcn = createCallbackFcn(app, @MANUALVALVEPOSTButtonPushed, true);
            app.MANUALVALVEPOSTButton.Text = 'MANUAL VALVE POST';
            app.SEQUENCESTARTButton = uibutton(app.GridLayout5, 'push');
            app.SEQUENCESTARTButton.ButtonPushedFcn = createCallbackFcn(app, @SEQUENCESTARTButtonPushed, true);
            app.SEQUENCESTARTButton.Text = 'SEQUENCE START';
            app.HOLDButton = uibutton(app.GridLayout5, 'push');
            app.HOLDButton.ButtonPushedFcn = createCallbackFcn(app, @HOLDButtonPushed, true);
            app.HOLDButton.Text = 'HOLD';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gith

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end